classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        LatchStateLabel          matlab.ui.control.Label
        StopLatchButton          matlab.ui.control.Button
        StartLatchButton         matlab.ui.control.Button
        LogButton                matlab.ui.control.Button
        SerialstateLabel         matlab.ui.control.Label
        StatusLabel              matlab.ui.control.Label
        msgLabel                 matlab.ui.control.Label
        TemperatureLabel         matlab.ui.control.Label
        EditField                matlab.ui.control.NumericEditField
        Gauge                    matlab.ui.control.Gauge
        ReadTelmetryButton       matlab.ui.control.Button
        ReadTemperatureButton    matlab.ui.control.Button
        CommandsLabel            matlab.ui.control.Label
        AppsLabel                matlab.ui.control.Label
        ConnectionLabel          matlab.ui.control.Label
        MotorsButton             matlab.ui.control.Button
        PingButton               matlab.ui.control.Button
        MemoryButton             matlab.ui.control.Button
        SunsensorButton          matlab.ui.control.Button
        MagnetometerButton       matlab.ui.control.Button
        GyroscopeButton          matlab.ui.control.Button
        ConnectButton            matlab.ui.control.Button
        UpdateSerialButton       matlab.ui.control.Button
        BaudRateEditField        matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel   matlab.ui.control.Label
        SerialPortDropDown       matlab.ui.control.DropDown
        SerialPortDropDownLabel  matlab.ui.control.Label
    end

    properties (Access = public)
        Serialdev % the serial port to which we connect
        GyroscopeHandle
        MagnetometerHandle
        SunsensorsHandle
        LogHandle
        MotorsHandle
    end

    methods (Access = public)

        function [status,array] = checkfends(app)     % this function is used to check the call back system
            array =[];                      % the raw frame
            persistent fends;  % fends counter
            if isempty(fends)   % initializing
                fends = 1;
            end
            if fends < 2
                fends = fends+1;
                status = 0;
            else
                fends = 1;
                array = read( app.Serialdev,app.Serialdev.NumBytesAvailable,"uint8");
                status = 1;
            end
        end
        function send(app,command,callback)
            if isempty(app.Serialdev)
                app.msgLabel.FontColor = 'r';
                app.msgLabel.Text = "Error : Serial is disconnected";
            else
                app.msgLabel.Text = "";
                app.msgLabel.FontColor = 'black';
                flush(app.Serialdev);
                command = Process(command,"out");
                configureCallback(app.Serialdev,"terminator",callback);
                write(app.Serialdev,command,"uint8");
            end
        end
    end
    methods (Access = private)
        function PingCallback(app,~,~)
            [flag, array] = checkfends(app);
            if flag
                [frame,status] = Process(array,"in");
                if status
                    app.msgLabel.Text = reshape(dec2hex(frame,2)',1,[]);
                end
            end
        end
        function StartLatchCallback(app,~,~)
            [flag, array] = checkfends(app);
            if flag
                [~,status] = Process(array,"in");
                if status
                    app.StartLatchButton.Visible = 'off';
                    app.StartLatchButton.Enable = 'off';
                    app.StopLatchButton.Visible = 'on';
                    app.StopLatchButton.Enable = 'on';
                    app.LatchStateLabel.FontColor = 'g';
                    app.LatchStateLabel.Text = 'Latch is on';
                end
            end
        end
        function StopLatchCallback(app,~,~)
            flush(app.Serialdev);
            app.LatchStateLabel.FontColor = 'r';
            app.LatchStateLabel.Text = 'Latch is off';
            app.StartLatchButton.Visible = 'on';
            app.StartLatchButton.Enable = 'on';
            app.StopLatchButton.Visible = 'off';
            app.StopLatchButton.Enable = 'off';
        end
        function TelmetryCallback(app,~,~)
            [flag, array] = checkfends(app);
            if flag
                [frame,status] = Process(array,"in");
                if status
                    Telmetry = zeros(12,"single");
                    for i=7:4:51
                        Telmetry((i-3)/4) = typecast((uint8(frame( i-3: i))),'single');
                    end
                    if ~isempty(app.GyroscopeHandle)
                        app.GyroscopeHandle.dispGyro(Telmetry(1:3));
                    end
                    if ~isempty(app.MagnetometerHandle)
                        app.MagnetometerHandle.dispMagneto(Telmetry(4:6));
                    end
                    app.dispTemp(Telmetry(7));
                    if ~isempty(app.SunsensorsHandle)
                        app.SunsensorsHandle.dispSunsensors(Telmetry(8:12));
                    end
                end
            end
        end

        function ErrorCallback(~,~,~)
            disp("ERROR");
        end
        function dispTemp(app,temperature)
            app.Gauge.Value=temperature;
            app.EditField.Value = double(temperature);
        end
        function TempCallback(app,~,~)
            [flag, array] = checkfends(app);
            if flag
                [frame,status] = Process(array,"in");
                if status
                    app.msgLabel.Text = reshape(dec2hex(frame,2)',1,[]);
                    app.dispTemp(typecast((uint8(frame( 4: 7))),'single'));
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [~,y]=system('wmic path win32_pnpentity get name /format:table |find "COM"');
            str = strsplit(y,'\n');
            str = strtrim(str);
            app.SerialPortDropDown.Items = str(1:end-1);
            app.SerialstateLabel.FontColor = 'r';
            app.SerialstateLabel.Text = "Disconnected";
            app.StopLatchButton.Visible = 'off';
            app.StopLatchButton.Enable = 'off';
            app.LatchStateLabel.FontColor = 'r';
            app.LatchStateLabel.Text = 'Latch is off';
        end

        % Button pushed function: UpdateSerialButton
        function UpdateSerialButtonPushed(app, event)
            [~,y]=system('wmic path win32_pnpentity get name /format:table |find "COM"');
            str = strsplit(y,'\n');
            str = strtrim(str);
            app.SerialPortDropDown.Items = str(1:end-1);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if isempty(app.Serialdev)
                serialname = convertStringsToChars(extractBetween( ...
                    app.SerialPortDropDown.Value,'(',')'));
                serialname = serialname{1};
                serialbaud = app.BaudRateEditField.Value;
                try
                    app.Serialdev = serialport(serialname,serialbaud);
                    configureTerminator(app.Serialdev,0xc0);
                    configureCallback(app.Serialdev,"terminator",@app.ErrorCallback);
                    app.SerialstateLabel.FontColor = 'g';
                    app.SerialstateLabel.Text = "Connected";
                    app.ConnectButton.Text = "Disconnect";
                catch
                    app.SerialstateLabel.Text = "Error : choose proper COM";
                end

            else
                app.Serialdev=[];
                app.SerialstateLabel.FontColor = 'r';
                app.SerialstateLabel.Text = "Disconnected";
                app.ConnectButton.Text = "Connect";
            end
        end

        % Button pushed function: PingButton
        function PingButtonPushed(app, event)
            command = [0x07,0x01,0x00];
            app.send(command,@app.PingCallback);
            %             command = Process(command,"out");
            %             configureCallback(app.Serialdev,"terminator",@app.PingCallback);
            %             write(app.Serialdev,command,"uint8");
        end

        % Button pushed function: ReadTemperatureButton
        function ReadTemperatureButtonPushed(app, event)
            command = [0x07,0x01,13];
            app.send(command,@app.TempCallback);
        end

        % Button pushed function: GyroscopeButton
        function GyroscopeButtonPushed(app, event)
            app.GyroscopeHandle = Gyroscopapp(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.GyroscopeHandle);
            delete(app.MagnetometerHandle);
            delete(app.SunsensorsHandle);
            delete(app.LogHandle);
            delete(app.MotorsHandle);
            delete(app);
        end

        % Button pushed function: MagnetometerButton
        function MagnetometerButtonPushed(app, event)
            app.MagnetometerHandle = Magnetometerapp(app);
        end

        % Button pushed function: SunsensorButton
        function SunsensorButtonPushed(app, event)
            app.SunsensorsHandle = Sunsensorsapp(app);
        end

        % Button pushed function: LogButton
        function LogButtonPushed(app, event)
            app.LogHandle = Logapp(app);
        end

        % Button pushed function: StartLatchButton
        function StartLatchButtonPushed(app, event)
            command = [0x07,0x01,24];
            command = Process(command,"out");
            configureCallback(app.Serialdev,"terminator",@app.StartLatchCallback);
            write(app.Serialdev,command,"uint8");
        end

        % Button pushed function: StopLatchButton
        function StopLatchButtonPushed(app, event)
            command = [0x07,0x01,25];
            command = Process(command,"out");
            configureCallback(app.Serialdev,"terminator",@app.StopLatchCallback);
            write(app.Serialdev,command,"uint8");
        end

        % Button pushed function: MotorsButton
        function MotorsButtonPushed(app, event)
            app.MotorsHandle = Motorsapp(app);
        end

        % Button pushed function: ReadTelmetryButton
        function ReadTelmetryButtonPushed(app, event)
            command = [0x07,0x01,15];
            command = Process(command,"out");
            configureCallback(app.Serialdev,"terminator",@app.TelmetryCallback);
            write(app.Serialdev,command,"uint8");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SerialPortDropDownLabel = uilabel(app.UIFigure);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.UIFigure);
            app.SerialPortDropDown.Items = {};
            app.SerialPortDropDown.Value = {};
            app.BaudRateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaudRateEditField.ValueDisplayFormat = '%.0f';
            app.BaudRateEditField.Value = 115200;
            app.UpdateSerialButton = uibutton(app.UIFigure, 'push');
            app.UpdateSerialButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSerialButtonPushed, true);
            app.UpdateSerialButton.Text = 'Update Serial';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.GyroscopeButton = uibutton(app.UIFigure, 'push');
            app.GyroscopeButton.ButtonPushedFcn = createCallbackFcn(app, @GyroscopeButtonPushed, true);
            app.GyroscopeButton.Text = 'Gyroscope';
            app.MagnetometerButton = uibutton(app.UIFigure, 'push');
            app.MagnetometerButton.ButtonPushedFcn = createCallbackFcn(app, @MagnetometerButtonPushed, true);
            app.MagnetometerButton.Text = 'Magnetometer';
            app.SunsensorButton = uibutton(app.UIFigure, 'push');
            app.SunsensorButton.ButtonPushedFcn = createCallbackFcn(app, @SunsensorButtonPushed, true);
            app.SunsensorButton.Text = 'Sunsensor';
            app.MemoryButton = uibutton(app.UIFigure, 'push');
            app.MemoryButton.Text = 'Memory';
            app.PingButton = uibutton(app.UIFigure, 'push');
            app.PingButton.ButtonPushedFcn = createCallbackFcn(app, @PingButtonPushed, true);
            app.PingButton.Text = 'Ping';
            app.MotorsButton = uibutton(app.UIFigure, 'push');
            app.MotorsButton.ButtonPushedFcn = createCallbackFcn(app, @MotorsButtonPushed, true);
            app.MotorsButton.Text = 'Motors';
            app.ConnectionLabel = uilabel(app.UIFigure);
            app.ConnectionLabel.Text = 'Connection';
            app.AppsLabel = uilabel(app.UIFigure);
            app.AppsLabel.Text = 'Apps';
            app.CommandsLabel = uilabel(app.UIFigure);
            app.CommandsLabel.Text = 'Commands';
            app.ReadTemperatureButton = uibutton(app.UIFigure, 'push');
            app.ReadTemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @ReadTemperatureButtonPushed, true);
            app.ReadTemperatureButton.Text = 'Read Temperature';
            app.ReadTelmetryButton = uibutton(app.UIFigure, 'push');
            app.ReadTelmetryButton.ButtonPushedFcn = createCallbackFcn(app, @ReadTelmetryButtonPushed, true);
            app.ReadTelmetryButton.Text = 'Read Telmetry';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureLabel = uilabel(app.UIFigure);
            app.TemperatureLabel.Text = 'Temperature';
            app.msgLabel = uilabel(app.UIFigure);
            app.msgLabel.Text = {''; ''};
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status :';
            app.SerialstateLabel = uilabel(app.UIFigure);
            app.SerialstateLabel.Text = '';
            app.LogButton = uibutton(app.UIFigure, 'push');
            app.LogButton.ButtonPushedFcn = createCallbackFcn(app, @LogButtonPushed, true);
            app.LogButton.Text = 'Log';
            app.StartLatchButton = uibutton(app.UIFigure, 'push');
            app.StartLatchButton.ButtonPushedFcn = createCallbackFcn(app, @StartLatchButtonPushed, true);
            app.StartLatchButton.Text = 'Start Latch';
            app.StopLatchButton = uibutton(app.UIFigure, 'push');
            app.StopLatchButton.ButtonPushedFcn = createCallbackFcn(app, @StopLatchButtonPushed, true);
            app.StopLatchButton.Text = 'Stop Latch';
            app.LatchStateLabel = uilabel(app.UIFigure);
            app.LatchStateLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end